classdef AE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ActualizarButton  matlab.ui.control.StateButton
        UITable           matlab.ui.control.Table
        text2             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        title             matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        indicesSel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerae)
            app.CallingApp = callerae;
            
            %Cargar base de datos 
            t = readtable("BaseDatosAJU.xlsx","Sheet",1,"Range","A:G");
            app.UITable.Data = t;
            app.UITable.ColumnName = t.Properties.VariableNames;
        end

        % Value changed function: ActualizarButton
        function ActualizarButtonValueChanged(app, event)
            %Botón para actualizar la información de la tabla en caso de
            %que no se haya cerrado esta ventana
            value = app.ActualizarButton.Value;
            t = readtable("BaseDatosAJU.xlsx","Sheet",1,"Range","A:G");
            app.UITable.Data = t;
            app.UITable.ColumnName = t.Properties.VariableNames;
            

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %Crear gráfico
            app.indicesSel = event.Indices;
            cla(app.UIAxes);
% 
            %Si se seleccionan las celdas de las columnas 2,3,4,5,6,7
            if app.indicesSel(:,2)~=8
                rowsSelected = app.indicesSel(:,1); % Filas seleccionadas
                ColSelected = app.indicesSel(:,2); % Columnas seleccionadas
                selectedCells = app.UITable.Data(rowsSelected,ColSelected); % Celdas seleccionadas 
                i=selectedCells{:,1};
                
%                 X = unique(selectedCells, 'stable') % Celdas únicas seleccionadas
%                 Totales = zeros(1,length(X)) %Inicializamos los totales por mes
            end
            controlchart(i,"charttype",'i','display','on','parent',app.UIAxes)
%             xname = app.UITable.ColumnName(ColSelected);
%             xlab =unique(xname, 'stable');
%             xlabel=xlab
%             


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Plan Anual de Mantenimiento del Simulador de Vuelo Airbus A320';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Text = 'Objetivo: Mantener en operación óptima el simulador';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Text = 'Por: Raymundo Bárcenas / Alejandra Amaya';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ActualizarButton = uibutton(app.UIFigure, 'state');
            app.ActualizarButton.ValueChangedFcn = createCallbackFcn(app, @ActualizarButtonValueChanged, true);
            app.ActualizarButton.Text = 'Actualizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end