classdef PAM22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        app_1                    matlab.ui.Figure
        AnlisisEstadsticoButton  matlab.ui.control.Button
        SeleccionarfechaLabel    matlab.ui.control.Label
        dia                      matlab.ui.control.DropDown
        DaLabel                  matlab.ui.control.Label
        meses                    matlab.ui.control.DropDown
        MesDropDownLabel         matlab.ui.control.Label
        Fecha                    matlab.ui.control.Label
        ContinuarButton          matlab.ui.control.Button
        currentyear              matlab.ui.control.Spinner
        AoSpinnerLabel           matlab.ui.control.Label
        text2                    matlab.ui.control.Label
        text3                    matlab.ui.control.Label
        title                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        enero % Enero app
        febrero % Febrero app
        marzo % Marzo app
        abril % Abril app
        mayo % Mayo app
        junio % Junio app
        julio % Julio app
        agosto % Agosto app
        septiembre % Septiembre app
        octubre % Octubre app
        noviembre % Noviembre app
        diciembre % Diciembre app
        AE % Análisis Estadístico
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            % Obtener el mes seleccionado en la lista desplegable
            mes=app.meses.Value;

            % Abrir la ventana correspondiente al mes seleccionado
            switch mes
                case 'Enero'
                    enero(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Febrero'
                    febrero(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Marzo'
                    marzo(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Abril'
                    abril(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Mayo'
                    mayo(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Junio'
                    junio(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Julio'
                    julio(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Agosto'
                    agosto(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Septiembre'
                    septiembre(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Octubre'
                    octubre(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Noviembre'
                    noviembre(app.Fecha.Text, app); % Pasa app como el segundo argumento
                case 'Diciembre'
                    diciembre(app.Fecha.Text, app); % Pasa app como el segundo argumento
            end
                    
        end

        % Value changed function: dia
        function diaValueChanged(app, event)
            value = app.dia.Value;
            
        end

        % Value changed function: meses
        function mesesValueChanged(app, event)
            value = app.meses.Value           
            d = num2str(app.dia.Value);
            m = value;
            y = num2str(app.currentyear.Value);

            txt = [d, ' de ', m, ' de ', y];
            app.Fecha.Text = txt;
        end

        % Value changed function: currentyear
        function currentyearValueChanged(app, event)
            value = app.currentyear.Value;
            
        end

        % Button pushed function: AnlisisEstadsticoButton
        function AnlisisEstadsticoButtonPushed(app, event)
            app.AE = AE(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.app_1 = uifigure('Visible', 'off');
            app.app_1.Name = 'MATLAB App';
            app.title = uilabel(app.app_1);
            app.title.Text = 'Plan Anual de Mantenimiento del Simulador de Vuelo Airbus A320';
            app.text3 = uilabel(app.app_1);
            app.text3.Text = 'Objetivo: Mantener en operación óptima el simulador';
            app.text2 = uilabel(app.app_1);
            app.text2.Text = 'Por: Raymundo Bárcenas / Alejandra Amaya';
            app.AoSpinnerLabel = uilabel(app.app_1);
            app.AoSpinnerLabel.Text = 'Año';
            app.currentyear = uispinner(app.app_1);
            app.currentyear.ValueChangedFcn = createCallbackFcn(app, @currentyearValueChanged, true);
            app.currentyear.Value = 2023;
            app.ContinuarButton = uibutton(app.app_1, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.Fecha = uilabel(app.app_1);
            app.Fecha.Text = 'FECHA';
            app.MesDropDownLabel = uilabel(app.app_1);
            app.MesDropDownLabel.Text = 'Mes';
            app.meses = uidropdown(app.app_1);
            app.meses.Items = {'', 'Enero', 'Febrero', 'Marzo', 'Abril', 'Mayo', 'Junio', 'Julio', 'Agosto', 'Septiembre', 'Octubre', 'Noviembre', 'Diciembre'};
            app.meses.ValueChangedFcn = createCallbackFcn(app, @mesesValueChanged, true);
            app.meses.Value = '';
            app.DaLabel = uilabel(app.app_1);
            app.DaLabel.Text = 'Día';
            app.dia = uidropdown(app.app_1);
            app.dia.Items = {'', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31'};
            app.dia.ValueChangedFcn = createCallbackFcn(app, @diaValueChanged, true);
            app.dia.Value = '';
            app.SeleccionarfechaLabel = uilabel(app.app_1);
            app.SeleccionarfechaLabel.Text = 'Seleccionar fecha:';
            app.AnlisisEstadsticoButton = uibutton(app.app_1, 'push');
            app.AnlisisEstadsticoButton.ButtonPushedFcn = createCallbackFcn(app, @AnlisisEstadsticoButtonPushed, true);
            app.AnlisisEstadsticoButton.Text = 'Análisis Estadístico';
            % Show the figure after all components are created
            app.app_1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAM22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.app_1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.app_1)
        end
    end
end