classdef abril < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        app_5                         matlab.ui.Figure
        GuardarButtonD                matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        DIARIOTab                     matlab.ui.container.Tab
        rd4                           matlab.ui.control.CheckBox
        rd3                           matlab.ui.control.CheckBox
        text4                         matlab.ui.control.Label
        rd2                           matlab.ui.control.CheckBox
        rd1                           matlab.ui.control.CheckBox
        QTGTab                        matlab.ui.container.Tab
        qtg21                         matlab.ui.control.CheckBox
        qtg20                         matlab.ui.control.CheckBox
        qtg19                         matlab.ui.control.CheckBox
        qtg18                         matlab.ui.control.CheckBox
        qtg17                         matlab.ui.control.CheckBox
        qtg16                         matlab.ui.control.CheckBox
        qtg15                         matlab.ui.control.CheckBox
        qtg14                         matlab.ui.control.CheckBox
        qtg13                         matlab.ui.control.CheckBox
        qtg12                         matlab.ui.control.CheckBox
        qtg11                         matlab.ui.control.CheckBox
        qtg10                         matlab.ui.control.CheckBox
        qtg9                          matlab.ui.control.CheckBox
        qtg8                          matlab.ui.control.CheckBox
        qtg7                          matlab.ui.control.CheckBox
        qtg6                          matlab.ui.control.CheckBox
        qtg5                          matlab.ui.control.CheckBox
        qtg4                          matlab.ui.control.CheckBox
        qtg3                          matlab.ui.control.CheckBox
        qtg2                          matlab.ui.control.CheckBox
        qtg1                          matlab.ui.control.CheckBox
        text5                         matlab.ui.control.Label
        obs                           matlab.ui.control.TextArea
        OBSERVACIONESTextAreaLabel    matlab.ui.control.Label
        MENSUALTab                    matlab.ui.container.Tab
        rm10                          matlab.ui.control.CheckBox
        rm9                           matlab.ui.control.CheckBox
        rm8                           matlab.ui.control.CheckBox
        rm7                           matlab.ui.control.CheckBox
        rm6                           matlab.ui.control.CheckBox
        rm5                           matlab.ui.control.CheckBox
        rm4                           matlab.ui.control.CheckBox
        rm3                           matlab.ui.control.CheckBox
        rm2                           matlab.ui.control.CheckBox
        rm1                           matlab.ui.control.CheckBox
        TRIMESTRALTab                 matlab.ui.container.Tab
        rt18                          matlab.ui.control.CheckBox
        rt17                          matlab.ui.control.CheckBox
        rt16                          matlab.ui.control.CheckBox
        rt15                          matlab.ui.control.CheckBox
        rt14                          matlab.ui.control.CheckBox
        rt13                          matlab.ui.control.CheckBox
        rt12                          matlab.ui.control.CheckBox
        A6                            matlab.ui.control.NumericEditField
        Acumulador6EditFieldLabel     matlab.ui.control.Label
        DB1                           matlab.ui.control.NumericEditField
        DownBridgeEditFieldLabel      matlab.ui.control.Label
        A5                            matlab.ui.control.NumericEditField
        Acumulador5EditFieldLabel     matlab.ui.control.Label
        psi3                          matlab.ui.control.Label
        A4                            matlab.ui.control.NumericEditField
        Acumulador4EditFieldLabel     matlab.ui.control.Label
        A3                            matlab.ui.control.NumericEditField
        Acumulador3EditFieldLabel     matlab.ui.control.Label
        CL1                           matlab.ui.control.NumericEditField
        ControlLoadingEditFieldLabel  matlab.ui.control.Label
        A2                            matlab.ui.control.NumericEditField
        Acumulador2EditFieldLabel     matlab.ui.control.Label
        psi2                          matlab.ui.control.Label
        A1                            matlab.ui.control.NumericEditField
        Acumulador1EditFieldLabel     matlab.ui.control.Label
        psi1                          matlab.ui.control.Label
        rt19                          matlab.ui.control.CheckBox
        rt11                          matlab.ui.control.CheckBox
        rt10                          matlab.ui.control.CheckBox
        rt9                           matlab.ui.control.CheckBox
        rt8                           matlab.ui.control.CheckBox
        rt7                           matlab.ui.control.CheckBox
        rt6                           matlab.ui.control.CheckBox
        rt5                           matlab.ui.control.CheckBox
        rt4                           matlab.ui.control.CheckBox
        rt3                           matlab.ui.control.CheckBox
        rt2                           matlab.ui.control.CheckBox
        rt1                           matlab.ui.control.CheckBox
        fecha                         matlab.ui.control.Label
        text2                         matlab.ui.control.Label
        text3                         matlab.ui.control.Label
        title                         matlab.ui.control.Label
        Exportar                      matlab.ui.container.ContextMenu
        ExportarpdfMenu               matlab.ui.container.Menu
    end

        
        properties (Access = private)
            CallingApp % app1 App
            
        end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Fecha, caller4)
                %Imprime la fecha seleccionada inicialmente en esta ventana
                app.fecha.Text = Fecha;
                %Para cargar los check boxes ya marcados anteriormente
                if exist('TareasMantto4.mat','file')
                    load('TareasMantto4.mat','cbqtg1','cbqtg2','cbqtg3','cbqtg4','cbqtg5',...
                        'cbqtg6','cbqtg7','cbqtg8','cbqtg9','cbqtg10','cbqtg11','cbqtg12',...
                        'cbqtg13','cbqtg14','cbqtg15','cbqtg16','cbqtg17','cbqtg18','cbqtg19',...
                        'cbqtg20','cbqtg21','cbobs','cbm1','cbm2','cbm3','cbm4','cbm5','cbm6',...
                        'cbm7','cbm8','cbm9','cbm10','cba1','cba2','cba3','cba4','cba5','cba6',...
                        'cbcl1','cbdb1','cbt1','cbt2','cbt3','cbt4','cbt5','cbt6','cbt7','cbt8',...
                        'cbt9','cbt10','cbt11','cbt12','cbt13','cbt14','cbt15','cbt16','cbt17',...
                        'cbt18','cbt19');
    
                    app.qtg1.Value=cbqtg1;
                    app.qtg2.Value=cbqtg2;
                    app.qtg3.Value=cbqtg3;
                    app.qtg4.Value=cbqtg4;
                    app.qtg5.Value=cbqtg5;
                    app.qtg6.Value=cbqtg6;
                    app.qtg7.Value=cbqtg7;
                    app.qtg8.Value=cbqtg8;
                    app.qtg9.Value=cbqtg9;
                    app.qtg10.Value=cbqtg10;
                    app.qtg11.Value=cbqtg11;
                    app.qtg12.Value=cbqtg12;
                    app.qtg13.Value=cbqtg13;
                    app.qtg14.Value=cbqtg14;
                    app.qtg15.Value=cbqtg15;
                    app.qtg16.Value=cbqtg16;
                    app.qtg17.Value=cbqtg17;
                    app.qtg18.Value=cbqtg18;
                    app.qtg19.Value=cbqtg19;
                    app.qtg20.Value=cbqtg20;
                    app.qtg21.Value=cbqtg21;
                    app.obs.Value=cbobs;
                    app.rm1.Value=cbm1;
                    app.rm2.Value=cbm2;
                    app.rm3.Value=cbm3;
                    app.rm4.Value=cbm4;
                    app.rm5.Value=cbm5;
                    app.rm6.Value=cbm6;
                    app.rm7.Value=cbm7;
                    app.rm8.Value=cbm8;
                    app.rm9.Value=cbm9;
                    app.rm10.Value=cbm10;
                    app.rt1.Value=cbt1;
                    app.rt2.Value=cbt2;
                    app.rt3.Value=cbt3;
                    app.rt4.Value=cbt4;
                    app.rt5.Value=cbt5;
                    app.rt6.Value=cbt6;
                    app.rt7.Value=cbt7;
                    app.rt8.Value=cbt8;
                    app.rt9.Value=cbt9;
                    app.rt10.Value=cbt10;
                    app.rt11.Value=cbt11;
                    app.rt12.Value=cbt12;
                    app.rt13.Value=cbt13;
                    app.rt14.Value=cbt14;
                    app.rt15.Value=cbt15;
                    app.rt16.Value=cbt16;
                    app.rt17.Value=cbt17;
                    app.rt18.Value=cbt18;
                    app.rt19.Value=cbt19;
                    app.A1.Value=cba1;
                    app.A2.Value=cba2;
                    app.A3.Value=cba3;
                    app.A4.Value=cba4;
                    app.A5.Value=cba5;
                    app.A6.Value=cba6;
                    app.CL1.Value=cbcl1;
                    app.DB1.Value=cbdb1;
                    
                end
    
                %Store main app object
                app.CallingApp = caller4;
                
    
        end

        % Button pushed function: GuardarButtonD
        function GuardarButtonDPushed(app, event)
                %Calculo de actividades realizadas diariamente
                Diario = app.rd1.Value + app.rd2.Value + app.rd3.Value + app.rd4.Value;
                Fecha = string(app.fecha.Text);
                QTG = app.qtg1.Value + app.qtg2.Value + app.qtg3.Value + app.qtg4.Value + app.qtg5.Value + app.qtg6.Value + app.qtg7.Value + app.qtg8.Value + app.qtg9.Value + app.qtg10.Value + app.qtg11.Value + app.qtg12.Value + app.qtg13.Value + app.qtg14.Value + app.qtg15.Value + app.qtg16.Value + app.qtg17.Value + app.qtg18.Value + app.qtg19.Value + app.qtg20.Value + app.qtg21.Value;
                Mensual = app.rm1.Value + app.rm2.Value + app.rm3.Value + app.rm4.Value + app.rm5.Value + app.rm6.Value + app.rm7.Value + app.rm8.Value + app.rm9.Value + app.rm10.Value;
                Trimestral = app.rt1.Value + app.rt2.Value + app.rt3.Value + app.rt4.Value + app.rt5.Value + app.rt6.Value + app.rt7.Value + app.rt8.Value + app.rt9.Value + app.rt10.Value + app.rt11.Value + app.rt12.Value+ app.rt13.Value+ app.rt14.Value+ app.rt15.Value+ app.rt16.Value+ app.rt17.Value+ app.rt18.Value+ app.rt19.Value;
                Semestral = 0;
                Anual = 0;
                %Se duplican las tablas con otro nombre para poder obtener
                %las tareas realizadas en un solo día (estas columnas no se
                %muestran dentro de la app, solo se guardan en el excel)
                TotalQTG = app.qtg1.Value + app.qtg2.Value + app.qtg3.Value + app.qtg4.Value + app.qtg5.Value + app.qtg6.Value + app.qtg7.Value + app.qtg8.Value + app.qtg9.Value + app.qtg10.Value + app.qtg11.Value + app.qtg12.Value + app.qtg13.Value + app.qtg14.Value + app.qtg15.Value + app.qtg16.Value + app.qtg17.Value + app.qtg18.Value + app.qtg19.Value + app.qtg20.Value + app.qtg21.Value;
                TotalMensual=app.rm1.Value + app.rm2.Value + app.rm3.Value + app.rm4.Value + app.rm5.Value + app.rm6.Value + app.rm7.Value + app.rm8.Value + app.rm9.Value + app.rm10.Value;
                TotalTrimestral = app.rt1.Value + app.rt2.Value + app.rt3.Value + app.rt4.Value + app.rt5.Value + app.rt6.Value + app.rt7.Value + app.rt8.Value + app.rt9.Value + app.rt10.Value + app.rt11.Value + app.rt12.Value+ app.rt13.Value+ app.rt14.Value+ app.rt15.Value+ app.rt16.Value+ app.rt17.Value+ app.rt18.Value+ app.rt19.Value;
                TotalSemestral = 0;
                TotalAnual = 0;

                %Crear una tabla en excel que guarde la información
                %anterior
                T = table(Fecha,Diario,QTG,Mensual,Trimestral,Semestral, Anual,TotalQTG,TotalMensual,TotalTrimestral,TotalSemestral, TotalAnual);
                filename = 'BaseDatos.xlsx';
                ruta = strcat('C:\Users\Usuario\Desktop\MANTENIMIENTO\',filename); %Cambiar a ubicación deseada
                existe = exist(ruta);
                
                %Si no existe un archivo llamado así, creará uno nuevo
                if existe == 0
                    writetable(T,filename,'Sheet',1,'Range','A1')
                
                %Si ya existe procederá a cargar la información
                else 
                    % Leer datos existentes
                    datos = readtable(filename);

                    % Filtrar datos por el mes de abril actual
                    CurrentYear = extract(Fecha,digitsPattern(4));
                    CurrentMonth = "Abril de ";
                    pat = CurrentMonth + CurrentYear;
                    datosAbril = datos(contains(datos.Fecha,pat), :);
                    

                    %Calcular sumatoria acumulada y nuevos valores que representan las tareas realizadas en el día para QTG, Mensual, Trimestral, Semestral, Anual solo de febrero
                    %NOTA: Las tareas diarias no se incluyen porque cada al
                    %repetirse cada dia no es necesario guardar sus valores

                    acumuladoQTG = sum(datosAbril.QTG);  
                    nuevoQTG = abs(TotalQTG- acumuladoQTG);

                    acumuladoMensual = sum(datosAbril.Mensual);
                    nuevoMensual = abs(TotalMensual - acumuladoMensual);
    
                    acumuladoTrimestral = sum(datosAbril.Trimestral);
                    nuevoTrimestral= abs(TotalTrimestral - acumuladoTrimestral);
    
                    %acumuladoSemestral = sum(datosAbril.Semestral);
                    %nuevoSemestral = abs(TotalSemestral - acumuladoSemestral);

                    %acumuladoAnual = sum(datosAbril.Anual);
                    %nuevoAnual = abs(TotalAnual - acumuladoAnual);
    
                    %Actualizar tabla con los nuevos valores según
                    %corresponda
                    T = table(Fecha, Diario, nuevoQTG, nuevoMensual, nuevoTrimestral, Semestral, Anual,TotalQTG,TotalMensual,TotalTrimestral,TotalSemestral, TotalAnual);
    
                    % Ajustar los nombres de las variables en la tabla T para que coincidan con datosEnero
                    T.Properties.VariableNames = datosAbril.Properties.VariableNames;
    
                    % Concatenar los datos originales con los datos filtrados por mes y año
                    TodasLasTareas = [datos; T];
                    
                    %Se actualiza la información en el archivo de excel
                    [fila,col] = size(datos);
                    celda = strcat('A',string(fila+2));
                    writetable(TodasLasTareas, filename, 'Sheet', 1, 'Range', 'A1', 'WriteVariableNames', true)
                end
    
                %Para guardar los check boxes marcados
                cbqtg1=app.qtg1.Value;
                cbqtg2=app.qtg2.Value;
                cbqtg3=app.qtg3.Value;
                cbqtg4=app.qtg4.Value;
                cbqtg5=app.qtg5.Value;
                cbqtg6=app.qtg6.Value;
                cbqtg7=app.qtg7.Value;
                cbqtg8=app.qtg8.Value;
                cbqtg9=app.qtg9.Value;
                cbqtg10=app.qtg10.Value;
                cbqtg11=app.qtg11.Value;
                cbqtg12=app.qtg12.Value;
                cbqtg13=app.qtg13.Value;
                cbqtg14=app.qtg14.Value;
                cbqtg15=app.qtg15.Value;
                cbqtg16=app.qtg16.Value;
                cbqtg17=app.qtg17.Value;
                cbqtg18=app.qtg18.Value;
                cbqtg19=app.qtg19.Value;
                cbqtg20=app.qtg20.Value;
                cbqtg21=app.qtg21.Value;
                cbobs=app.obs.Value;
                cbm1=app.rm1.Value;
                cbm2=app.rm2.Value;
                cbm3=app.rm3.Value;
                cbm4=app.rm4.Value;
                cbm5=app.rm5.Value;
                cbm6=app.rm6.Value;
                cbm7=app.rm7.Value;
                cbm8=app.rm8.Value;
                cbm9=app.rm9.Value;
                cbm10=app.rm10.Value;
                cbt1=app.rt1.Value;
                cbt2=app.rt2.Value;
                cbt3=app.rt3.Value;
                cbt4=app.rt4.Value;
                cbt5=app.rt5.Value;
                cbt6=app.rt6.Value;
                cbt7=app.rt7.Value;
                cbt8=app.rt8.Value;
                cbt9=app.rt9.Value;
                cbt10=app.rt10.Value;
                cbt11=app.rt11.Value;
                cbt12=app.rt12.Value;
                cbt13=app.rt13.Value;
                cbt14=app.rt14.Value;
                cbt15=app.rt15.Value;
                cbt16=app.rt16.Value;
                cbt17=app.rt17.Value;
                cbt18=app.rt18.Value;
                cbt19=app.rt19.Value;
                cba1=app.A1.Value;
                cba2=app.A2.Value;
                cba3=app.A3.Value;
                cba4=app.A4.Value;
                cba5=app.A5.Value;
                cba6=app.A6.Value;
                cbcl1=app.CL1.Value;
                cbdb1=app.DB1.Value;
                
    
                save('TareasMantto4.mat',"cbqtg1","cbqtg2","cbqtg3","cbqtg4","cbqtg5",...
                    'cbqtg6','cbqtg7','cbqtg8','cbqtg9','cbqtg10','cbqtg11','cbqtg12',...
                    'cbqtg13','cbqtg14','cbqtg15','cbqtg16','cbqtg17','cbqtg18','cbqtg19',...
                    'cbqtg20','cbqtg21','cbobs','cbm1','cbm2','cbm3','cbm4','cbm5','cbm6',...
                    'cbm7','cbm8','cbm9','cbm10','cba1','cba2','cba3','cba4','cba5','cba6',...
                    'cbcl1','cbdb1','cbt1','cbt2','cbt3','cbt4','cbt5','cbt6','cbt7','cbt8',...
                    'cbt9','cbt10','cbt11','cbt12','cbt13','cbt14','cbt15','cbt16','cbt17',...
                    'cbt18','cbt19');
    
        end

        % Menu selected function: ExportarpdfMenu
        function ExportarpdfMenuSelected(app, event)
            % Pide al usuario que elija la ubicación de guardado del PDF
            [filename, pathname] = uiputfile('abril.pdf', 'Guardar como PDF');
        
            % Verifica si el usuario hizo clic en 'Cancelar'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
        
            % Combina todas las pestañas del Tab Group en un solo documento PDF
            appTabs = app.TabGroup.Children;  % Obtén todos los hijos del Tab Group

            % Crea un archivo PDF temporal para almacenar las páginas individuales
            tempPDF = fullfile(pathname, 'temp.pdf');
            
            for i = 1:numel(appTabs)
                % Cambia a la pestaña actual
                app.TabGroup.SelectedTab = appTabs(i);
            
                % Toma una captura de pantalla de la pestaña actual y la guarda como página individual
                pageFileName = fullfile(pathname, ['page_' num2str(i) '.pdf']);
                exportapp(app.app_5, pageFileName);
            end
            
            % Combina los archivos PDF individuales en un solo archivo PDF
            pdfFiles = strcat(fullfile(pathname, 'page_'), strsplit(num2str(1:numel(appTabs)), ' '), '.pdf');
            append_pdfs(tempPDF, pdfFiles{:});
            
            % Renombra el archivo temporal al nombre deseado
            movefile(tempPDF, fullfile(pathname, filename));
            
            % Elimina los archivos PDF individuales
            for i = 1:numel(appTabs)
                delete(fullfile(pathname, ['page_' num2str(i) '.pdf']));
            end
            
            % Mensaje de confirmación
            msgbox('El reporte ha sido exportado a PDF con éxito.', 'Éxito', 'help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.app_5 = uifigure('Visible', 'off');
            app.app_5.Name = 'MATLAB App';
            app.title = uilabel(app.app_5);
            app.title.Text = 'Plan Anual de Mantenimiento del Simulador de Vuelo Airbus A320';
            app.text3 = uilabel(app.app_5);
            app.text3.Text = 'Objetivo: Mantener en operación óptima el simulador';
            app.text2 = uilabel(app.app_5);
            app.text2.Text = 'Por: Raymundo Bárcenas / Alejandra Amaya';
            app.fecha = uilabel(app.app_5);
            app.fecha.Text = 'FECHA';
            app.TabGroup = uitabgroup(app.app_5);
            app.DIARIOTab = uitab(app.TabGroup);
            app.DIARIOTab.Title = 'DIARIO';
            app.rd1 = uicheckbox(app.DIARIOTab);
            app.rd1.Text = 'Elaboración de reportes diarios y bitácora de horas de vuelo.';
            app.rd2 = uicheckbox(app.DIARIOTab);
            app.rd2.Text = 'Efectuar mantenimientocorrectivo en simulador o taller (si es necesario)';
            app.text4 = uilabel(app.DIARIOTab);
            app.text4.Text = 'Si el equipo se encuentra encendido completamente:';
            app.rd3 = uicheckbox(app.DIARIOTab);
            app.rd3.Text = 'Inspeccionar y corregir (si es necesario) fugas en la máquina hidráulica, examinar mangueras, conexiones, manómetros e indicaciones luminosas y de presión.';
            app.rd4 = uicheckbox(app.DIARIOTab);
            app.rd4.Text = 'Verificar correcta operación de las indicaciones luminosas en los tableros de control de cabina y equipo exterior.';
            app.QTGTab = uitab(app.TabGroup);
            app.QTGTab.Title = 'QTG''s';
            app.OBSERVACIONESTextAreaLabel = uilabel(app.QTGTab);
            app.OBSERVACIONESTextAreaLabel.Text = 'OBSERVACIONES';
            app.obs = uitextarea(app.QTGTab);
            app.text5 = uilabel(app.QTGTab);
            app.text5.Text = 'Realizar pruebas QTG correspondientes al primer semestre de IAE';
            app.qtg1 = uicheckbox(app.QTGTab);
            app.qtg1.Text = '2a 6.1n';
            app.qtg2 = uicheckbox(app.QTGTab);
            app.qtg2.Text = '2a 7.1n';
            app.qtg3 = uicheckbox(app.QTGTab);
            app.qtg3.Text = '2a 8.1n';
            app.qtg4 = uicheckbox(app.QTGTab);
            app.qtg4.Text = '2b 3.1d1';
            app.qtg5 = uicheckbox(app.QTGTab);
            app.qtg5.Text = '2b 3.1d2';
            app.qtg6 = uicheckbox(app.QTGTab);
            app.qtg6.Text = '2b 4.5d1';
            app.qtg7 = uicheckbox(app.QTGTab);
            app.qtg7.Text = '2b 4.5d2';
            app.qtg8 = uicheckbox(app.QTGTab);
            app.qtg8.Text = '2b 4.5d3';
            app.qtg9 = uicheckbox(app.QTGTab);
            app.qtg9.Text = '2b 4.7d1';
            app.qtg10 = uicheckbox(app.QTGTab);
            app.qtg10.Text = '2b 4.7d2';
            app.qtg11 = uicheckbox(app.QTGTab);
            app.qtg11.Text = '2b 4.7d3';
            app.qtg12 = uicheckbox(app.QTGTab);
            app.qtg12.Text = '2c 1.7d';
            app.qtg13 = uicheckbox(app.QTGTab);
            app.qtg13.Text = '2c 1.7n';
            app.qtg14 = uicheckbox(app.QTGTab);
            app.qtg14.Text = '2c 2.3d';
            app.qtg15 = uicheckbox(app.QTGTab);
            app.qtg15.Text = '2c 2.3n';
            app.qtg16 = uicheckbox(app.QTGTab);
            app.qtg16.Text = '2c 2.7d';
            app.qtg17 = uicheckbox(app.QTGTab);
            app.qtg17.Text = '2c 2.7n';
            app.qtg18 = uicheckbox(app.QTGTab);
            app.qtg18.Text = '2c 3.5d1';
            app.qtg19 = uicheckbox(app.QTGTab);
            app.qtg19.Text = '2c 3.5d2';
            app.qtg20 = uicheckbox(app.QTGTab);
            app.qtg20.Text = '2c 3.5n1';
            app.qtg21 = uicheckbox(app.QTGTab);
            app.qtg21.Text = '2c 3.5n2';
            app.MENSUALTab = uitab(app.TabGroup);
            app.MENSUALTab.Title = 'MENSUAL';
            app.rm1 = uicheckbox(app.MENSUALTab);
            app.rm1.Text = 'Efectuar mantenimiento a equipo de apoyo (aspiradora, aspiradora antiestática, motor tool, lámparas de banco, etc.).';
            app.rm2 = uicheckbox(app.MENSUALTab);
            app.rm2.Text = 'Verificar operación de instrumentos de medición (multímetros, osciloscopios, flukes, etc.).';
            app.rm3 = uicheckbox(app.MENSUALTab);
            app.rm3.Text = 'Servicio a sección UF (limpieza, aspirado e inspección de fugas en sistema hidráulico, bandas dentadas, pernos, rudder, etc.).';
            app.rm4 = uicheckbox(app.MENSUALTab);
            app.rm4.Text = 'Servicio a máquina hidráulica (limpieza, nivel de tanque, indicaciones, etc.).';
            app.rm5 = uicheckbox(app.MENSUALTab);
            app.rm5.Text = 'Servicio a puente de acceso, mangueras flexibles hidráulicas, acumuladores hidráulicas y sistema de emergencia.';
            app.rm6 = uicheckbox(app.MENSUALTab);
            app.rm6.Text = 'Verificar fuentes de alimentación del sistema.';
            app.rm7 = uicheckbox(app.MENSUALTab);
            app.rm7.Text = 'Verificar fuentes de alimentación del IG Visual.';
            app.rm8 = uicheckbox(app.MENSUALTab);
            app.rm8.Text = 'Servicio de aspirado y limpieza de proyectores del sistema visual y revisión de conectores de alimentación.';
            app.rm9 = uicheckbox(app.MENSUALTab);
            app.rm9.Text = 'Servicio al equipo periférico del cuarto de cómputo.';
            app.rm10 = uicheckbox(app.MENSUALTab);
            app.rm10.Text = 'Checar discos de las computadoras Host y Master i/o y actualizar de ser necesario.';
            app.TRIMESTRALTab = uitab(app.TabGroup);
            app.TRIMESTRALTab.Title = 'TRIMESTRAL';
            app.rt1 = uicheckbox(app.TRIMESTRALTab);
            app.rt1.Text = 'Servicio de limpieza a mascarillas de O2.';
            app.rt2 = uicheckbox(app.TRIMESTRALTab);
            app.rt2.Text = 'Servicio a malla interna de la máquina hidráulica.';
            app.rt3 = uicheckbox(app.TRIMESTRALTab);
            app.rt3.Text = 'Limpieza de filtro de aire IG.';
            app.rt4 = uicheckbox(app.TRIMESTRALTab);
            app.rt4.Text = 'Servicio a base de movimiento, examinar actuadores, válvulas, limpieza y lubricación de rótulas superiores e inferiores (si es necesario), aliviador, barra de MicroSw y lubricación de bujes (si es necesario).';
            app.rt5 = uicheckbox(app.TRIMESTRALTab);
            app.rt5.Text = 'Checar switches de límite de actuadores de base de movimiento.';
            app.rt6 = uicheckbox(app.TRIMESTRALTab);
            app.rt6.Text = 'Verificar imagen de los proyectores y alinear de ser necesario.';
            app.rt7 = uicheckbox(app.TRIMESTRALTab);
            app.rt7.Text = 'Limpieza y aspirado general de cabina (paneles, ventanillas, tabletos, etc.).';
            app.rt8 = uicheckbox(app.TRIMESTRALTab);
            app.rt8.Text = 'Checar sistema de THS, tensión de cadena, acoplamiento, lubricación, limpieza, etc.';
            app.rt9 = uicheckbox(app.TRIMESTRALTab);
            app.rt9.Text = 'Servicio a fuentes de alimentación de la sección Ufl, Ufg, Ufm y Bahia M. Limpieza y verificación de nivel.';
            app.rt10 = uicheckbox(app.TRIMESTRALTab);
            app.rt10.Text = 'Verificar operación de todos los ventiladores.';
            app.rt11 = uicheckbox(app.TRIMESTRALTab);
            app.rt11.Text = 'Verificar apriete de tornillos de la base de los asientos de los pilotos';
            app.rt19 = uicheckbox(app.TRIMESTRALTab);
            app.rt19.Text = 'Verificar y recargar (si es necesario) presión de acumuladores.';
            app.psi1 = uilabel(app.TRIMESTRALTab);
            app.psi1.Text = '900 psi';
            app.Acumulador1EditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.Acumulador1EditFieldLabel.Text = 'Acumulador 1';
            app.A1 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.psi2 = uilabel(app.TRIMESTRALTab);
            app.psi2.Text = '700 psi';
            app.Acumulador2EditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.Acumulador2EditFieldLabel.Text = 'Acumulador 2';
            app.A2 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.ControlLoadingEditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.ControlLoadingEditFieldLabel.Text = 'Control Loading';
            app.CL1 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.Acumulador3EditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.Acumulador3EditFieldLabel.Text = 'Acumulador 3';
            app.A3 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.Acumulador4EditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.Acumulador4EditFieldLabel.Text = 'Acumulador 4';
            app.A4 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.psi3 = uilabel(app.TRIMESTRALTab);
            app.psi3.Text = '600 psi';
            app.Acumulador5EditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.Acumulador5EditFieldLabel.Text = 'Acumulador 5';
            app.A5 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.DownBridgeEditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.DownBridgeEditFieldLabel.Text = 'Down Bridge';
            app.DB1 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.Acumulador6EditFieldLabel = uilabel(app.TRIMESTRALTab);
            app.Acumulador6EditFieldLabel.Text = 'Acumulador 6';
            app.A6 = uieditfield(app.TRIMESTRALTab, 'numeric');
            app.rt12 = uicheckbox(app.TRIMESTRALTab);
            app.rt12.Text = 'Servicio a computadoras PC 604 de todos los gabinetes (Host, Master i/o, Radar, Arinc, Mos, Cos).';
            app.rt13 = uicheckbox(app.TRIMESTRALTab);
            app.rt13.Text = 'Servicio y limpieza de terminales de fibra óptica.';
            app.rt14 = uicheckbox(app.TRIMESTRALTab);
            app.rt14.Text = 'Verificar operación del sistema de movimiento (recalibrar de ser necesario).';
            app.rt15 = uicheckbox(app.TRIMESTRALTab);
            app.rt15.Text = 'Verificar calibración del sistema de control loading (recalibrar de ser necesario).';
            app.rt16 = uicheckbox(app.TRIMESTRALTab);
            app.rt16.Text = 'Servicio de limpieza y aspirado de chasises del IG Rack, VME, IGI y Control power.';
            app.rt17 = uicheckbox(app.TRIMESTRALTab);
            app.rt17.Text = 'Checar cilindro del puente de acceso.';
            app.rt18 = uicheckbox(app.TRIMESTRALTab);
            app.rt18.Text = 'Revisión de la válvula de alivio de presión.';
            app.GuardarButtonD = uibutton(app.app_5, 'push');
            app.GuardarButtonD.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonDPushed, true);
            app.GuardarButtonD.Text = 'Guardar';
            app.Exportar = uicontextmenu(app.app_5);
            app.ExportarpdfMenu = uimenu(app.Exportar);
            app.ExportarpdfMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarpdfMenuSelected, true);
            app.ExportarpdfMenu.Text = 'Exportar .pdf';
            % Assign app.Exportar
            app.app_5.ContextMenu = app.Exportar;
            % Show the figure after all components are created
            app.app_5.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = abril(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.app_5)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.app_5)
        end
    end
end