classdef diciembre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        app_13                      matlab.ui.Figure
        GuardarButtonD              matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        DIARIOTab                   matlab.ui.container.Tab
        rd4                         matlab.ui.control.CheckBox
        rd3                         matlab.ui.control.CheckBox
        text4                       matlab.ui.control.Label
        rd2                         matlab.ui.control.CheckBox
        rd1                         matlab.ui.control.CheckBox
        QTGTab                      matlab.ui.container.Tab
        qtg6                        matlab.ui.control.CheckBox
        qtg5                        matlab.ui.control.CheckBox
        qtg4                        matlab.ui.control.CheckBox
        qtg3                        matlab.ui.control.CheckBox
        qtg2                        matlab.ui.control.CheckBox
        obs                         matlab.ui.control.TextArea
        qtg1                        matlab.ui.control.CheckBox
        OBSERVACIONESTextAreaLabel  matlab.ui.control.Label
        text5                       matlab.ui.control.Label
        MENSUALTab                  matlab.ui.container.Tab
        rm10                        matlab.ui.control.CheckBox
        rm9                         matlab.ui.control.CheckBox
        rm8                         matlab.ui.control.CheckBox
        rm7                         matlab.ui.control.CheckBox
        rm6                         matlab.ui.control.CheckBox
        rm5                         matlab.ui.control.CheckBox
        rm4                         matlab.ui.control.CheckBox
        rm3                         matlab.ui.control.CheckBox
        rm2                         matlab.ui.control.CheckBox
        rm1                         matlab.ui.control.CheckBox
        ANUALTab                    matlab.ui.container.Tab
        ra7                         matlab.ui.control.CheckBox
        ra6                         matlab.ui.control.CheckBox
        ra5                         matlab.ui.control.CheckBox
        ra4                         matlab.ui.control.CheckBox
        ra3                         matlab.ui.control.CheckBox
        ra2                         matlab.ui.control.CheckBox
        ra1                         matlab.ui.control.CheckBox
        fecha                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        text3                       matlab.ui.control.Label
        title                       matlab.ui.control.Label
        Exportar                    matlab.ui.container.ContextMenu
        ExportarpdfMenu             matlab.ui.container.Menu
    end

        
        properties (Access = private)
            CallingApp % app1 App
            
        end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Fecha, caller12)
                %Imprime la fecha seleccionada inicialmente en esta ventana
                app.fecha.Text = Fecha;
                %Para cargar los check boxes ya marcados anteriormente
                if exist('TareasMantto12.mat','file')
                    load('TareasMantto12.mat','cbqtg1','cbqtg2','cbqtg3','cbqtg4','cbqtg5',...
                        'cbqtg6','cbobs','cbm1','cbm2','cbm3','cbm4','cbm5','cbm6','cbm7',...
                        'cbm8','cbm9','cbm10','cbra1','cbra2','cbra3','cbra4','cbra5','cbra6','cbra7');
    
                    app.qtg1.Value=cbqtg1;
                    app.qtg2.Value=cbqtg2;
                    app.qtg3.Value=cbqtg3;
                    app.qtg4.Value=cbqtg4;
                    app.qtg5.Value=cbqtg5;
                    app.qtg6.Value=cbqtg6;
                    app.obs.Value=cbobs;
                    app.rm1.Value=cbm1;
                    app.rm2.Value=cbm2;
                    app.rm3.Value=cbm3;
                    app.rm4.Value=cbm4;
                    app.rm5.Value=cbm5;
                    app.rm6.Value=cbm6;
                    app.rm7.Value=cbm7;
                    app.rm8.Value=cbm8;
                    app.rm9.Value=cbm9;
                    app.rm10.Value=cbm10;
                    app.ra1.Value=cbra1;
                    app.ra2.Value=cbra2;
                    app.ra3.Value=cbra3;
                    app.ra4.Value=cbra4;
                    app.ra5.Value=cbra5;
                    app.ra6.Value=cbra6;
                    app.ra7.Value=cbra7;
                    
                    
                end
    
                %Store main app object
                app.CallingApp = caller12;
                
    
        end

        % Button pushed function: GuardarButtonD
        function GuardarButtonDPushed(app, event)
                %Calculo de actividades realizadas diariamente
                Diario = app.rd1.Value + app.rd2.Value + app.rd3.Value + app.rd4.Value;
                Fecha = string(app.fecha.Text);
                QTG = app.qtg1.Value + app.qtg2.Value + app.qtg3.Value + app.qtg4.Value + app.qtg5.Value + app.qtg6.Value;
                Mensual = app.rm1.Value + app.rm2.Value + app.rm3.Value + app.rm4.Value + app.rm5.Value + app.rm6.Value + app.rm7.Value + app.rm8.Value + app.rm9.Value + app.rm10.Value;
                Trimestral = 0;
                Semestral = 0;
                Anual = app.ra1.Value + app.ra2.Value + app.ra3.Value + app.ra4.Value + app.ra5.Value + app.ra6.Value + app.ra7.Value;
                %Se duplican las tablas con otro nombre para poder obtener
                %las tareas realizadas en un solo día (estas columnas no se
                %muestran dentro de la app, solo se guardan en el excel)
                TotalQTG = app.qtg1.Value + app.qtg2.Value + app.qtg3.Value + app.qtg4.Value + app.qtg5.Value + app.qtg6.Value;
                TotalMensual=app.rm1.Value + app.rm2.Value + app.rm3.Value + app.rm4.Value + app.rm5.Value + app.rm6.Value + app.rm7.Value + app.rm8.Value + app.rm9.Value + app.rm10.Value;
                TotalTrimestral = 0;
                TotalSemestral = 0;
                TotalAnual = app.ra1.Value + app.ra2.Value + app.ra3.Value + app.ra4.Value + app.ra5.Value + app.ra6.Value + app.ra7.Value;
    
                %Crear una tabla en excel que guarde la información
                %anterior
                T = table(Fecha,Diario,QTG,Mensual,Trimestral,Semestral, Anual,TotalQTG,TotalMensual,TotalTrimestral,TotalSemestral, TotalAnual);
                filename = 'BaseDatos.xlsx';
                ruta = strcat('C:\Users\Usuario\Desktop\MANTENIMIENTO\',filename); %Cambiar a ubicación deseada
                existe = exist(ruta);
    
                %Si no existe un archivo llamado así, creará uno nuevo
                if existe == 0
                    writetable(T,filename,'Sheet',1,'Range','A1')

                %Si ya existe procederá a cargar la información
                else 
                    % Leer datos existentes
                    datos = readtable(filename);

                    % Filtrar datos por el mes de abril actual
                    CurrentYear = extract(Fecha,digitsPattern(4));
                    CurrentMonth = "Diciembre de ";
                    pat = CurrentMonth + CurrentYear;
                    datosDiciembre = datos(contains(datos.Fecha,pat), :);

                    %Calcular sumatoria acumulada y nuevos valores que representan las tareas realizadas en el día para QTG, Mensual, Trimestral, Semestral, Anual solo de febrero
                    %NOTA: Las tareas diarias no se incluyen porque cada al
                    %repetirse cada dia no es necesario guardar sus valores

                    acumuladoQTG = sum(datosDiciembre.QTG);
                    nuevoQTG = abs(TotalQTG - acumuladoQTG);

                    acumuladoMensual = sum(datosDiciembre.Mensual);
                    nuevoMensual = abs(TotalMensual - acumuladoMensual);
    
                    %acumuladoTrimestral = sum(datosDiciembre.Trimestral);
                    %nuevoTrimestral= abs(TotalTrimestral - acumuladoTrimestral);
    
                    %acumuladoSemestral = sum(datosDiciembre.Semestral);
                    %nuevoSemestral = abs(TotalSemestral - acumuladoSemestral);

                    acumuladoAnual = sum(datosDiciembre.Anual);
                    nuevoAnual = abs(TotalAnual - acumuladoAnual);
    
                    %Actualizar tabla con los nuevos valores según
                    %corresponda
                    T = table(Fecha, Diario, nuevoQTG, nuevoMensual, Trimestral, Semestral, nuevoAnual,TotalQTG,TotalMensual,TotalTrimestral,TotalSemestral, TotalAnual);
    
                    % Ajustar los nombres de las variables en la tabla T para que coincidan con datosEnero
                    T.Properties.VariableNames = datosDiciembre.Properties.VariableNames;
    
                    % Concatenar los datos originales con los datos filtrados por mes y año
                    TodasLasTareas = [datos; T];
                    
                    %Se actualiza la información en el archivo de excel
                    [fila,col] = size(datos);
                    celda = strcat('A',string(fila+2));
                    writetable(TodasLasTareas, filename, 'Sheet', 1, 'Range', 'A1', 'WriteVariableNames', true)
                end
    
                %Para guardar los check boxes marcados
                cbqtg1=app.qtg1.Value;
                cbqtg2=app.qtg2.Value;
                cbqtg3=app.qtg3.Value;
                cbqtg4=app.qtg4.Value;
                cbqtg5=app.qtg5.Value;
                cbqtg6=app.qtg6.Value;
                cbobs=app.obs.Value;
                cbm1=app.rm1.Value;
                cbm2=app.rm2.Value;
                cbm3=app.rm3.Value;
                cbm4=app.rm4.Value;
                cbm5=app.rm5.Value;
                cbm6=app.rm6.Value;
                cbm7=app.rm7.Value;
                cbm8=app.rm8.Value;
                cbm9=app.rm9.Value;
                cbm10=app.rm10.Value;
                cbra1=app.ra1.Value;
                cbra2=app.ra2.Value;
                cbra3=app.ra3.Value;
                cbra4=app.ra4.Value;
                cbra5=app.ra5.Value;
                cbra6=app.ra6.Value;
                cbra7=app.ra7.Value;
                
    
                save('TareasMantto12.mat',"cbqtg1","cbqtg2","cbqtg3","cbqtg4","cbqtg5",...
                        "cbqtg6","cbobs","cbm1","cbm2","cbm3","cbm4","cbm5","cbm6","cbm7",...
                        "cbm8","cbm9","cbm10","cbra1","cbra2","cbra3","cbra4","cbra5","cbra6","cbra7");
    
        end

        % Menu selected function: ExportarpdfMenu
        function ExportarpdfMenuSelected(app, event)
            % Pide al usuario que elija la ubicación de guardado del PDF
            [filename, pathname] = uiputfile('diciembre.pdf', 'Guardar como PDF');
        
            % Verifica si el usuario hizo clic en 'Cancelar'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
        
            % Combina todas las pestañas del Tab Group en un solo documento PDF
            appTabs = app.TabGroup.Children;  % Obtén todos los hijos del Tab Group

            % Crea un archivo PDF temporal para almacenar las páginas individuales
            tempPDF = fullfile(pathname, 'temp.pdf');
            
            for i = 1:numel(appTabs)
                % Cambia a la pestaña actual
                app.TabGroup.SelectedTab = appTabs(i);
            
                % Toma una captura de pantalla de la pestaña actual y la guarda como página individual
                pageFileName = fullfile(pathname, ['page_' num2str(i) '.pdf']);
                exportapp(app.app_13, pageFileName);
            end
            
            % Combina los archivos PDF individuales en un solo archivo PDF
            pdfFiles = strcat(fullfile(pathname, 'page_'), strsplit(num2str(1:numel(appTabs)), ' '), '.pdf');
            append_pdfs(tempPDF, pdfFiles{:});
            
            % Renombra el archivo temporal al nombre deseado
            movefile(tempPDF, fullfile(pathname, filename));
            
            % Elimina los archivos PDF individuales
            for i = 1:numel(appTabs)
                delete(fullfile(pathname, ['page_' num2str(i) '.pdf']));
            end
            
            % Mensaje de confirmación
            msgbox('El reporte ha sido exportado a PDF con éxito.', 'Éxito', 'help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.app_13 = uifigure('Visible', 'off');
            app.app_13.Name = 'MATLAB App';
            app.title = uilabel(app.app_13);
            app.title.Text = 'Plan Anual de Mantenimiento del Simulador de Vuelo Airbus A320';
            app.text3 = uilabel(app.app_13);
            app.text3.Text = 'Objetivo: Mantener en operación óptima el simulador';
            app.text2 = uilabel(app.app_13);
            app.text2.Text = 'Por: Raymundo Bárcenas / Alejandra Amaya';
            app.fecha = uilabel(app.app_13);
            app.fecha.Text = 'FECHA';
            app.TabGroup = uitabgroup(app.app_13);
            app.DIARIOTab = uitab(app.TabGroup);
            app.DIARIOTab.Title = 'DIARIO';
            app.rd1 = uicheckbox(app.DIARIOTab);
            app.rd1.Text = 'Elaboración de reportes diarios y bitácora de horas de vuelo.';
            app.rd2 = uicheckbox(app.DIARIOTab);
            app.rd2.Text = 'Efectuar mantenimientocorrectivo en simulador o taller (si es necesario)';
            app.text4 = uilabel(app.DIARIOTab);
            app.text4.Text = 'Si el equipo se encuentra encendido completamente:';
            app.rd3 = uicheckbox(app.DIARIOTab);
            app.rd3.Text = 'Inspeccionar y corregir (si es necesario) fugas en la máquina hidráulica, examinar mangueras, conexiones, manómetros e indicaciones luminosas y de presión.';
            app.rd4 = uicheckbox(app.DIARIOTab);
            app.rd4.Text = 'Verificar correcta operación de las indicaciones luminosas en los tableros de control de cabina y equipo exterior.';
            app.QTGTab = uitab(app.TabGroup);
            app.QTGTab.Title = 'QTG''s';
            app.text5 = uilabel(app.QTGTab);
            app.text5.Text = 'Realizar pruebas QTG correspondientes al segundo semestre de CFM';
            app.OBSERVACIONESTextAreaLabel = uilabel(app.QTGTab);
            app.OBSERVACIONESTextAreaLabel.Text = 'OBSERVACIONES';
            app.qtg1 = uicheckbox(app.QTGTab);
            app.qtg1.Text = '1b 01.1n';
            app.obs = uitextarea(app.QTGTab);
            app.qtg2 = uicheckbox(app.QTGTab);
            app.qtg2.Text = '1b.02.1d';
            app.qtg3 = uicheckbox(app.QTGTab);
            app.qtg3.Text = '1b.02.2d';
            app.qtg4 = uicheckbox(app.QTGTab);
            app.qtg4.Text = '1b.04.1n';
            app.qtg5 = uicheckbox(app.QTGTab);
            app.qtg5.Text = '1b.04.2n';
            app.qtg6 = uicheckbox(app.QTGTab);
            app.qtg6.Text = '1b.05.1n';
            app.MENSUALTab = uitab(app.TabGroup);
            app.MENSUALTab.Title = 'MENSUAL';
            app.rm1 = uicheckbox(app.MENSUALTab);
            app.rm1.Text = 'Efectuar mantenimiento a equipo de apoyo (aspiradora, aspiradora antiestática, motor tool, lámparas de banco, etc.).';
            app.rm2 = uicheckbox(app.MENSUALTab);
            app.rm2.Text = 'Verificar operación de instrumentos de medición (multímetros, osciloscopios, flukes, etc.).';
            app.rm3 = uicheckbox(app.MENSUALTab);
            app.rm3.Text = 'Servicio a sección UF (limpieza, aspirado e inspección de fugas en sistema hidráulico, bandas dentadas, pernos, rudder, etc.).';
            app.rm4 = uicheckbox(app.MENSUALTab);
            app.rm4.Text = 'Servicio a máquina hidráulica (limpieza, nivel de tanque, indicaciones, etc.).';
            app.rm5 = uicheckbox(app.MENSUALTab);
            app.rm5.Text = 'Servicio a puente de acceso, mangueras flexibles hidráulicas, acumuladores hidráulicas y sistema de emergencia.';
            app.rm6 = uicheckbox(app.MENSUALTab);
            app.rm6.Text = 'Verificar fuentes de alimentación del sistema.';
            app.rm7 = uicheckbox(app.MENSUALTab);
            app.rm7.Text = 'Verificar fuentes de alimentación del IG Visual.';
            app.rm8 = uicheckbox(app.MENSUALTab);
            app.rm8.Text = 'Servicio de aspirado y limpieza de proyectores del sistema visual y revisión de conectores de alimentación.';
            app.rm9 = uicheckbox(app.MENSUALTab);
            app.rm9.Text = 'Servicio al equipo periférico del cuarto de cómputo.';
            app.rm10 = uicheckbox(app.MENSUALTab);
            app.rm10.Text = 'Checar discos de las computadoras Host y Master i/o y actualizar de ser necesario.';
            app.ANUALTab = uitab(app.TabGroup);
            app.ANUALTab.Title = 'ANUAL';
            app.ra1 = uicheckbox(app.ANUALTab);
            app.ra1.Text = 'Verificar sistema de emergencia del puente de acceso.';
            app.ra2 = uicheckbox(app.ANUALTab);
            app.ra2.Text = 'Ajuste preventivo del puente de acceso.';
            app.ra3 = uicheckbox(app.ANUALTab);
            app.ra3.Text = 'Limpieza y aspirado del panel de control de la máquina hidráulica.';
            app.ra4 = uicheckbox(app.ANUALTab);
            app.ra4.Text = 'Verificación de los termostatos de temperatura de aceite.';
            app.ra5 = uicheckbox(app.ANUALTab);
            app.ra5.Text = 'Verificación de los transductores de presión de los servoactuadores.';
            app.ra6 = uicheckbox(app.ANUALTab);
            app.ra6.Text = 'Verificación de OFFSET de los servoactuadores de movimiento.';
            app.ra7 = uicheckbox(app.ANUALTab);
            app.ra7.Text = 'Verificar interruptor de flotador de bajo nivel.';
            app.GuardarButtonD = uibutton(app.app_13, 'push');
            app.GuardarButtonD.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonDPushed, true);
            app.GuardarButtonD.Text = 'Guardar';
            app.Exportar = uicontextmenu(app.app_13);
            app.ExportarpdfMenu = uimenu(app.Exportar);
            app.ExportarpdfMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarpdfMenuSelected, true);
            app.ExportarpdfMenu.Text = 'Exportar .pdf';
            % Assign app.Exportar
            app.app_13.ContextMenu = app.Exportar;
            % Show the figure after all components are created
            app.app_13.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diciembre(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.app_13)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.app_13)
        end
    end
end